classdef ref_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReflectionCoefficentPlotterUIFigure  matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        ReflectionCoefficientPlotterLabel  matlab.ui.control.Label
        GeometrySettingsPanel           matlab.ui.container.Panel
        nmLabel                         matlab.ui.control.Label
        FreespacewavelengthEditFieldLabel  matlab.ui.control.Label
        FreespacewavelengthEditField    matlab.ui.control.NumericEditField
        PermittivitiesEditFieldLabel    matlab.ui.control.Label
        PermittivitiesEditField         matlab.ui.control.EditField
        PermeabilitesEditFieldLabel     matlab.ui.control.Label
        PermeabilitesEditField          matlab.ui.control.EditField
        ThicknessesEditFieldLabel       matlab.ui.control.Label
        ThicknessesEditField            matlab.ui.control.EditField
        nmLabel_2                       matlab.ui.control.Label
        PolarizationSwitchLabel         matlab.ui.control.Label
        PolarizationSwitch              matlab.ui.control.Switch
        k_gammaLabel                    matlab.ui.control.Label
        k_gammaEditField                matlab.ui.control.EditField
        k_0Label                        matlab.ui.control.Label
        THzLabel                        matlab.ui.control.Label
        SpectralDomainSettingsk_xnormalizedwrtk_0Panel  matlab.ui.container.Panel
        StartLabel                      matlab.ui.control.Label
        StopLabel                       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        RealpartLabel                   matlab.ui.control.Label
        ImaginarypartLabel              matlab.ui.control.Label
        start_re                        matlab.ui.control.NumericEditField
        stop_re                         matlab.ui.control.NumericEditField
        n_re                            matlab.ui.control.NumericEditField
        start_im                        matlab.ui.control.NumericEditField
        stop_im                         matlab.ui.control.NumericEditField
        n_im                            matlab.ui.control.NumericEditField
        PermittivityValuesofMetalsPanel  matlab.ui.container.Panel
        AuLabel                         matlab.ui.control.Label
        AgLabel                         matlab.ui.control.Label
        au_val                          matlab.ui.control.Label
        ag_val                          matlab.ui.control.Label
        AlLabel                         matlab.ui.control.Label
        al_val                          matlab.ui.control.Label
        PlotTypeButtonGroup             matlab.ui.container.ButtonGroup
        ReButton                        matlab.ui.control.RadioButton
        ImButton                        matlab.ui.control.RadioButton
        absButton                       matlab.ui.control.RadioButton
        log10absButton                  matlab.ui.control.RadioButton
        angleButton                     matlab.ui.control.RadioButton
        PLOTButton                      matlab.ui.control.Button
        AdditionalInformationPanel      matlab.ui.container.Panel
        TrytofindsingularitiesCheckBox  matlab.ui.control.CheckBox
        PlotbranchcutsCheckBox          matlab.ui.control.CheckBox
        imagsearchlineEditFieldLabel    matlab.ui.control.Label
        imagsearchlineEditField         matlab.ui.control.NumericEditField
        k_0Label_2                      matlab.ui.control.Label
        onDropDownLabel                 matlab.ui.control.Label
        kx_or_kgamma_dropdown           matlab.ui.control.DropDown
        planeLabel                      matlab.ui.control.Label
        DescriptionButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        c0=299792458; %speed of light in free-space (m/s)
        wav %wavelength. (m)
        k0 %wavenumber in free-space (rad/m)
        freq %frequency. (Hz)
        eps %epsilon values. (Dimesionless)
        mu %mu values. (Dimensionless)
        pol; %polarization (0+1: TE, 1+1: TM)
        d; %thickness value. (m)
        re_start; %start value of the real part of the krho values.
        re_stop; %stop value of the real part of the krho values.
        re_n; %number of values of the real part of the krho values.
        im_start; %start value of the imaginary part of the krho values.
        im_stop; %stop value of the imaginary part of the krho values.
        im_n; %number of values of the imaginary part of the krho values.
        plot_flag; %plot flag value is 3 (real part).
        root_flag; %value for root search flag.
        branch_flag; %value for brach cut plot flag.
        NN; %value for number of layers.
        kgamma; %value for k_gamma. (normalized wrt k_0)
        roots; % singularities of the reflection coefficients. (normalized wrt k_0)
        branches % Branch points of the layered geometry.
    end
    
    methods (Access = private)
        
        function ress = eps_val(app,mat)
            %permittivity value of gold(1) (Palik), silver(2) (Johnson),
            %and aluminium(3)
            if mat==1
                data_gold =[3.000000000000000e-07    -9.436000000000000e-01     5.356200000000000e+00
                    3.050000000000000e-07    -9.081000000000000e-01     5.470600000000000e+00
                    3.100000000000000e-07    -8.748000000000000e-01     5.606300000000000e+00
                    3.150000000000000e-07    -7.974000000000000e-01     5.698800000000000e+00
                    3.200000000000000e-07    -7.031000000000000e-01     5.773600000000000e+00
                    3.250000000000000e-07    -5.832000000000001e-01     5.821100000000000e+00
                    3.300000000000000e-07    -4.781000000000000e-01     5.797600000000000e+00
                    3.350000000000000e-07    -3.793000000000000e-01     5.743500000000000e+00
                    3.400000000000000e-07    -3.707000000000000e-01     5.672300000000000e+00
                    3.450000000000000e-07    -3.732000000000000e-01     5.600100000000000e+00
                    3.500000000000000e-07    -4.382000000000000e-01     5.529800000000000e+00
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                    1.600000000000002e-06    -1.117066000000000e+02     4.007000000000000e+00];
                indd=sum(app.wav>=data_gold(:,1));
                if (indd==length(data_gold) || indd==0)
%                     fprintf('ERROR: Wavelength value out of bounds. Should be betweeen 300-1600nm for Au.\n');
                    ress=[];
                    return;
                end
                diff_wav_tab=data_gold(indd+1,1)-data_gold(indd,1);
                diff_dat_tab=data_gold(indd+1,2)+data_gold(indd+1,3)*1j-data_gold(indd,2)-data_gold(indd,3)*1j;
                diff_wav=app.wav-data_gold(indd,1);
                ress=data_gold(indd,2)+data_gold(indd,3)*1j+diff_dat_tab*diff_wav/diff_wav_tab;
            elseif mat==2
                data_silver=[2.715750000000000e-07     2.025545600000000e-02     3.787000000000000e+00
                    2.731500000000000e-07     6.294040400000001e-02     3.780136000000000e+00
                    2.747250000000001e-07     1.055784000000000e-01     3.773063000000000e+00
                    2.763000000000000e-07     1.481137000000000e-01     3.765727000000000e+00
                    2.778750000000000e-07     1.905742000000000e-01     3.758157000000000e+00
                    2.794500000000000e-07     2.297179000000000e-01     3.739433000000000e+00
                    2.810250000000000e-07     2.634744000000000e-01     3.703365000000000e+00
                    2.826000000000001e-07     2.969298000000000e-01     3.667269000000000e+00
                    2.841750000000000e-07     3.300575000000000e-01     3.631173000000000e+00
                    2.857500000000000e-07     3.628320000000000e-01     3.595105000000000e+00
                    2.873250000000000e-07     3.964416000000000e-01     3.554391000000000e+00
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                    9.000000000000002e-07    -4.024169000000000e+01     5.075008000000000e-01];
                indd=sum(app.wav>=data_silver(:,1));
                if (indd==length(data_silver) || indd==0)
%                     fprintf('ERROR: Wavelength value out of bounds. Should be between 272-900 nm for Ag.\n');
                    ress=[];
                    return;
                end
                diff_wav_tab=data_silver(indd+1,1)-data_silver(indd,1);
                diff_dat_tab=data_silver(indd+1,2)+data_silver(indd+1,3)*1j-data_silver(indd,2)-data_silver(indd,3)*1j;
                diff_wav=app.wav-data_silver(indd,1);
                ress=data_silver(indd,2)+data_silver(indd,3)*1j+diff_dat_tab*diff_wav/diff_wav_tab;
            elseif mat==3
                data_aluminium=[1.83492400000000e-07,-4.33633700000000,0.435285900000000
                    1.84872900000000e-07,-4.41277700000000,0.446762900000000
                    1.86274300000000e-07,-4.50057400000000,0.457243400000000
                    1.87697100000000e-07,-4.59640300000000,0.467268500000000
                    1.89141800000000e-07,-4.69680000000000,0.477400000000000
                    1.90608900000000e-07,-4.79815600000000,0.488218700000000
                    1.92099000000000e-07,-4.89669900000000,0.500328900000000
                    1.93612500000000e-07,-4.99328500000000,0.513558000000000
                    1.95150100000000e-07,-5.08881700000000,0.527724600000000
                    1.96712300000000e-07,-5.18423900000000,0.542640000000000
                    1.98299700000000e-07,-5.28052100000000,0.558108300000000
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                    2.47714400000000e-06,-645.935100000000,157.184000000000];
                indd=sum(app.wav>=data_aluminium(:,1));
                if (indd==length(data_aluminium) || indd==0)
                    fprintf('ERROR: Wavelength value out of bounds. Should be between 184-2470 nm for Al.\n');
                    ress=[];
                    return;
                end
                diff_wav_tab=data_aluminium(indd+1,1)-data_aluminium(indd,1);
                diff_dat_tab=data_aluminium(indd+1,2)+data_aluminium(indd+1,3)*1j-data_aluminium(indd,2)-data_aluminium(indd,3)*1j;
                diff_wav=app.wav-data_aluminium(indd,1);
                ress=data_aluminium(indd,2)+data_aluminium(indd,3)*1j+diff_dat_tab*diff_wav/diff_wav_tab;
            else
                warning('Unrecognized material in eps_val');
                ress=[];
            end 
        end

        function metal_check(app,a)
            %checks if there is a metal in the permittivities
            kk=isnan(app.eps);
            for ii=1:length(kk)
                if kk(ii)
                    b=string(a(ii));
                    if sum(strcmp(b,{'*au','*aU','*Au','*AU'}))
                        eps_au=eps_val(app,1);
                        if ~isempty(eps_au)
                            app.eps(ii)=eps_au;
                        else
                            errordlg('Check the permittivity values: out of bounds for Au.');
                            app.PermittivitiesEditField.FontColor='red';
                        end
                    elseif sum(strcmp(b,{'*ag','*aG','*Ag','*AG'}))
                        eps_ag=eps_val(app,2);
                        if ~isempty(eps_ag)
                            app.eps(ii)=eps_ag;
                        else
                            errordlg('Check the permittivity values: out of bounds for Ag.');
                            app.PermittivitiesEditField.FontColor='red';
                        end
                    elseif sum(strcmp(b,{'*al','*aL','*Al','*AL'}))
                        eps_al=eps_val(app,3);
                        if ~isempty(eps_al)
                            app.eps(ii)=eps_al;
                        else
                            errordlg('Check the permittivity values: out of bounds for Al.');
                            app.PermittivitiesEditField.FontColor='red';
                        end
                    else
                        errordlg('Check the permittivity values: they should be numeric, seperated by a space or comma');
                        app.PermittivitiesEditField.FontColor='red';
                    end
                end
            end
        end
        
        function result=refdata(app,krho,E,M,D,layer_from,layer_to,genorfres,pol_indent,kgamma)
            % REFLECTION COEFFICIENT CALCULATOR
            % krho: wave vector along rho direction.
            % N: number of layers.
            % f: frequecy of operation.
            % E: permittivities starting from the first layer (lowermost one)
            % M: permeabilities starting from the first layer (lowermost one)
            % D: thicknesses.
            % layer_from: the layer that the reflection coefficient is defined from.
            % layer_to: the layer that the reflection coefficient is defined to.
            % genorfres: 1: generalized reflection coeff. 2: fresnel reflection coeff.
            % pol_indent: 1: TE 2: TM
            % k_gamma: wavenumber value in y direction.
            
            if abs(layer_from-layer_to)~=1
                error('Please check the layers of the reflection coefficient');
            end
            
            if layer_from>layer_to
                if layer_from==1
                    result=0;
                    return;
                elseif layer_from<1
                    error('out of bounds of layer numbers, error in refdata... \n');
                end
                E_data=E(layer_from:-1:1);
                M_data=M(layer_from:-1:1);
                D_data=D(layer_to-1:-1:1);
                N_data=length(E_data);
            elseif layer_from<layer_to
                if layer_from==length(E)
                    result=0;
                    return;
                elseif layer_from>length(E)
                    error('out of bounds of layer numbers, error in refdata... \n');
                end
                E_data=E(layer_from:length(E));
                M_data=M(layer_from:length(M));
                D_data=D(layer_to-1:length(D));
                N_data=length(E_data);
            end
            if genorfres==1
                if pol_indent==1
                    result=full_RTE(app,krho,N_data,E_data,M_data,D_data,kgamma);
                elseif pol_indent==2
                    result=full_RTM(app,krho,N_data,E_data,M_data,D_data,kgamma);
                else
                    error('Please check the polarization of the reflection coefficient');
                end
            elseif genorfres==2
                if pol_indent==1
                    result=full_RTE(app,krho,2,E_data,M_data,D_data,kgamma);
                elseif pol_indent==2
                    result=full_RTM(app,krho,2,E_data,M_data,D_data,kgamma);
                else
                    error('Please check the polarization of the reflection coefficient');
                end
            else
                error('Please determine whether the generalized of fresnel ref. coeff. is needed');
            end
        end
        
        function result=full_RTE(app,krho,N,E,M,D,kgamma)
            %TE-mode reflection coefficent for a grid
            if N==1
                result=0;
                return;
            end
            
            kk=size(krho);
            result=zeros(size(krho));
            for i=1:kk(1)
                for j=1:kk(2)
                    result(i,j)=GRTE2(app,krho(i,j),N,E,M,D,kgamma);
                end
            end
        end
        
        function [gr_te]=GRTE2(app,krho,N,E,M,D,kgamma)
            %Generalized reflection coefficient, TE-mode for a vector
            %eps=-0.99 and mu=-0.99 means PEC boundary
                        
            kix=krho;
            kz=sqrt(((E.*M).*(2*pi*app.freq/app.c0)^2)-kix^2-kgamma^2); % kz value in each layer
            
            for klm=1:length(kz)
                if imag(kz(klm))<0 % Sommerfeld radiation condition for exp(-i \omega t)
                    kz(klm)=-kz(klm);
                end
            end
            
            tt=1;
            R=zeros(1,N-1);
            for t=2:N % fresnel reflection coefficient
                R(tt)=(M(t)*kz(t-1)-M(t-1)*kz(t))/(M(t)*kz(t-1)+M(t-1)*kz(t));%TE
                tt=tt+1;
            end
            GRC=zeros(1,N-1); %generalized reflection coefficient
            if E(N)==-0.99 && M(N)==-0.99
                GRC(N-1)=-1;
            else
                GRC(N-1)=R(N-1);
            end
            
            for t=N-2:-1:1
                GRC(t)=(R(t)+GRC(t+1)*exp(1i*kz(t+1)*2*D(t)))/(1+GRC(t+1)*R(t)*exp(1i*kz(t+1)*2*D(t)));
            end
            gr_te=GRC(1);
        end
        
        function result=full_RTM(app,krho,N,E,M,D,kgamma)
            %TM-mode reflection coefficent for a grid
            if N==1
                result=0;
                return;
            end
            
            kk=size(krho);
            result=zeros(size(krho));
            for i=1:kk(1)
                for j=1:kk(2)
                    result(i,j)=GRTM2(app,krho(i,j),N,E,M,D,kgamma);
                end
            end
        end
        
        function [gr_tm]=GRTM2(app,krho,N,E,M,D,kgamma)
            %Generalized reflection coefficient, TM-mode for a vector
            %eps=-0.99 and mu=-0.99 means PEC boundary
            
            kix=krho;
            kz=sqrt(((E.*M).*(2*pi*app.freq/app.c0)^2)-kix^2-kgamma^2); % kz value in each layer
            
            for klm=1:length(kz)
                if imag(kz(klm))<0 % Sommerfeld radiation condition for exp(-i \omega t)
                    kz(klm)=-kz(klm);
                end
            end
            
            tt=1;
            R=zeros(1,N-1);
            for t=2:N % fresnel reflection coefficients
                R(tt)=(E(t)*kz(t-1)-E(t-1)*kz(t))/(E(t)*kz(t-1)+E(t-1)*kz(t));%TM
                tt=tt+1;
            end
            GRC=zeros(1,N-1); % generalized reflection coefficients
            if E(N)==-0.99 && M(N)==-0.99
                GRC(N-1)=1;
            else
                GRC(N-1)=R(N-1);
            end
            
            for t=N-2:-1:1
                GRC(t)=(R(t)+GRC(t+1)*exp(1i*kz(t+1)*2*D(t)))/(1+GRC(t+1)*R(t)*exp(1i*kz(t+1)*2*D(t)));
            end
            gr_tm=GRC(1);
        end
        
        function [ reff ] = refdata_plane( app,krhoreal,krhoimag,pol_indent )
            % REFLECTION COEFFICIENTS PLOTTER ON A PLANE
            % the inputs for the krhoreal, krhoimag and kgamma are normalized with k0
            % app.plot_flag=
            % 1: real
            % 2: imag
            % 3: abs
            % 4: log10(abs)
            % 5: angle
            figr=figure();
            krhoreal=krhoreal.*app.k0;
            krhoimag=krhoimag.*app.k0;
            kgamma_in=app.kgamma*app.k0;
            reff=zeros(length(krhoreal),length(krhoimag));
            krho_real=zeros(length(krhoreal),length(krhoimag));
            krho_imag=zeros(length(krhoreal),length(krhoimag));
            E=app.eps;
            M=app.mu;
            D=app.d;
            layer_from=app.NN;
            layer_to=app.NN-1;
            genorfres=1;
            f_wait = waitbar(0,'Calculating reflection coefficient...');
            for ii=1:length(krhoimag)
                krho_real(:,ii)=krhoreal;
                krho_imag(:,ii)=krhoimag(ii).*ones(size(krhoreal));
                reff(:,ii)=refdata(app,krhoreal+1j*krhoimag(ii),E,M,D,layer_from,layer_to,genorfres,pol_indent,kgamma_in);
                waitbar(ii/length(krhoimag),f_wait,'Calculating reflection coefficient...');
            end
            waitbar(1,f_wait,'Plotting');
            figure(figr);
            if length(krhoimag)==1
                if app.plot_flag==1
                    plot(krho_real./app.k0,real(reff));
                    ylabel('$\Re(R)$','Interpreter',"latex");
                elseif app.plot_flag==2
                    plot(krho_real./app.k0,imag(reff));
                    ylabel('$\Im(R)$','Interpreter',"latex");
                elseif app.plot_flag==3
                    plot(krho_real./app.k0,abs(reff));
                    ylabel('$|R|$',"Interpreter","latex");
                elseif app.plot_flag==4
                    plot(krho_real./app.k0,log10(abs(reff)));
                    ylabel('$\log_{10}|R|$',"Interpreter","latex");
                else
                    plot(krho_real./app.k0,angle(reff));
                    ylabel('$\angle(R)$',"Interpreter","latex");
                end
                xlabel('$\Re(k_x)$',"Interpreter","latex");
                if pol_indent==1
                    title('TE-mode reflection coefficient');
                else
                    title('TM-mode reflection coefficient');
                end
            elseif length(krhoreal)==1
                if app.plot_flag==1
                    plot(krho_imag./app.k0,real(reff));
                    ylabel('$\Re(R)$','Interpreter',"latex");
                elseif app.plotflag==2
                    plot(krho_imag./app.k0,imag(reff));
                    ylabel('$\Im(R)$','Interpreter',"latex");
                elseif app.plot_flag==3
                    plot(krho_imag./app.k0,abs(reff));
                    ylabel('$|R|$',"Interpreter","latex");
                elseif app.plot_flag==4
                    plot(krho_imag./app.k0,log10(abs(reff)));
                    ylabel('$\log_{10}|R|$',"Interpreter","latex");
                else
                    plot(krho_imag./app.k0,angle(reff));
                    ylabel('$\angle(R)$',"Interpreter","latex");
                end
                xlabel('$\Im(k_x)$','Interpreter',"latex");
                if pol_indent==1
                    title('TE-mode reflection coefficient');
                else
                    title('TM-mode reflection coefficient');
                end
            else
                if app.plot_flag==1
                    contourf(krho_real./app.k0,krho_imag./app.k0,real(reff));
                    titlex='$\Re(R)$';
                elseif app.plot_flag==2
                    contourf(krho_real./app.k0,krho_imag./app.k0,imag(reff));
                    titlex='$\Im(R)$';
                elseif app.plot_flag==3
                    contourf(krho_real./app.k0,krho_imag./app.k0,abs(reff));
                    titlex='$|R|$';
                elseif app.plot_flag==4
                    contourf(krho_real./app.k0,krho_imag./app.k0,log10(abs(reff)));
                    titlex='$\log_{10}|R|$';
                else
                    contourf(krho_real./app.k0,krho_imag./app.k0,angle(reff));
                    titlex='$\angle(R)$';
                end
                xlabel('$\Re(k_x)$','Interpreter',"latex");
                ylabel('$\Im(k_x)$','Interpreter',"latex");
                colormap('Jet');
                axis equal;
                colorbar;
                if pol_indent==1
                    title(['TE-mode ' titlex],"Interpreter","latex");
                elseif pol_indent==2
                    title(['TM-mode ' titlex],"Interpreter","latex");
                end
            end
            grid on;
            close(f_wait);
        end
    
        function result=bisectionte(app,startt,stopt,tol,N,E,M,D)
            
            stt=real(1/full_RTE(app,startt,N,E,M,D,0));
            sto=real(1/full_RTE(app,stopt,N,E,M,D,0));
            
            if stt*sto>0
                result=0;
                return;
            end
            
            stt_new=startt;
            sto_new=stopt;
            iter=0;
            while abs((stt_new-sto_new)/stt_new)>tol || iter<1000
                iter=iter+1;
                aver=abs(stt_new-sto_new)/2;
                mid_new=stt_new+aver;
                
                stt=real(1/full_RTE(app,stt_new,N,E,M,D,0));
                mid=real(1/full_RTE(app,mid_new,N,E,M,D,0));
                sto=real(1/full_RTE(app,sto_new,N,E,M,D,0));
                
                if stt*mid<0
                    sto_new=mid_new;
                elseif mid*sto>0
                    stt_new=mid_new;
                else
                    if stt==0 && sto==0 && mid==0
                        result=0;
                        return;
                    else
                        result=mid_new;
                        return;
                    end
                end
            end
            result=mid_new;
        end
        
        function result=bisectiontm(app,startt,stopt,tol,N,E,M,D)
            
            stt=real(1/full_RTM(app,startt,N,E,M,D,0));
            sto=real(1/full_RTM(app,stopt,N,E,M,D,0));
            
            if stt*sto>0
                result=0;
                return;
            end
            
            stt_new=startt;
            sto_new=stopt;
            iter=0;
            while abs((stt_new-sto_new)/stt_new)>tol || iter<1000
                iter=iter+1;
                aver=abs(stt_new-sto_new)/2;
                mid_new=stt_new+aver;
                
                stt=real(1/full_RTM(app,stt_new,N,E,M,D,0));
                mid=real(1/full_RTM(app,mid_new,N,E,M,D,0));
                sto=real(1/full_RTM(app,sto_new,N,E,M,D,0));
                
                if stt*mid<0
                    sto_new=mid_new;
                elseif mid*sto>0
                    stt_new=mid_new;
                else
                    if stt==0 && sto==0 && mid==0
                        result=0;
                        return;
                    else
                        result=mid_new;
                        return;
                    end
                end
            end
            result=mid_new;
        end
    
        function [res,fval,it] = muller (app,f,Z0,itmax,ztol,ftol,option) %#ok<INUSL> 
            % MULLER find a zero of a real or complex function Y = F(Z)
            %
            % Syntax:
            %
            % RES = MULLER (F,Z0) find the zero of a complex or real function
            % 'F' (either an anonymous function or .m function) using three initial
            % guesses contained in the vector Z0. Muller takes the function F and
            % evaluetes it at each initial point using feval. F doesn't need to be
            % vectorized.
            % The initial guesses can be real or complex numbers close to the zero,
            % bracketing the zero is not necessary. Parameters ITMAX, ZTOL and
            % FTOL are set by default to 1000, 1e-5 and 1e-5, respectively.
            %
            % RES = MULLER (F,Z0,ITMAX) the maximum number of iterations is set
            % equal to ITMAX. ZTOL and FTOL are set by default with the values mentioned
            % above.
            %
            % RES = MULLER (F,Z0,ITMAX,ZTOL) ZTOL is used as a stopping
            % criterion. If the absolute difference between the values of Z found in
            % the two latest iterations is less than ZTOL, the program is stopped. FTOL
            % is set by default with the value mentioned above.
            %
            % RES = MULLER (F,Z0,ITMAX,ZTOL,FTOL) FTOL is used as a stopping
            % criterion. If the value of the function F at the Z found in the last
            % iteration is less than FTOL, the program is stopped.
            %
            % RES = MULLER (F,Z0,ITMAX,ZTOL,FTOL,'both') indicate that both
            % criteria ZTOL and FTOL must be satisfied simultaneously. By default,
            % MULLER stops if one of the two criteria is fulfilled.
            %
            % [RES,FVAL] = MULLER (F,Z0,...) return the value of the function
            % F at the Z found in the last iteration.
            %
            % [RES,FVAL,IT] = MULLER (F,Z0,...) return the number of iterations
            % used to find the zero.
            %
            % Example 1:
            % myf = @(x) (x-1)^3;
            %
            % muller(myf,[0 0.1 0.2],[],[],[],'both')
            % ans =
            %    1.0000 + 0.0000i
            %
            % Example 2:
            %
            % [res,fval,it] = muller2('cosh',[0 0.1 0.2],[],[],[],'both')
            %
            % res =
            %   0.0000 + 1.5708i
            %
            % fval =
            %   5.5845e-012 + 3.0132e-012i
            %
            % it =
            %   5
            %
            % Method taken from:
            % Numerical Recipes: The art of scientific computing
            % W.H. Press; B.P. Flannery; S.A. Teukolsky; W.T. Vetterling
            % 1986
            %
            % Thanks to John D'Errico for his helpfull review.
            %
            % Written by Daniel H. Cortes
            % MAE Department, West Virginia University
            % March, 2008.
            %
            
            
            %=================================================
            % Checking proper values of the input parameters
            %=================================================
            
            
            if nargin > 7
                error ('Too many arguments.')
            elseif nargin < 3
                error('Too few arguments.')
            end
            
            if nargin < 7
                opt = 1;
            elseif ischar(option) == 1
                if size(option,2) == 4
                    if sum(option == 'both') == 4
                        opt = 2;
                    else
                        error ('Option parameter must be *both*.')
                    end
                else
                    error ('Option parameter must be *both*.')
                end
            else
                error ('Option parameter must be a character array (string).')
            end
            
            
            if nargin < 6
                ftol = 1e-5;
            elseif isnumeric(ftol) ~= 1
                error ('FTOL must be a numeric argument.')
            elseif isempty(ftol) == 1
                ftol = 1e-5;
            elseif  size(ftol,1) ~= 1 || size(ftol,2) ~= 1
                error ('FTOL cannot be an array')
            end
            
            
            if nargin < 5
                ztol = 1e-5;
            elseif isnumeric(ztol) ~= 1
                error ('ZTOL must be a numeric argument.')
            elseif isempty(ztol) == 1
                ztol = 1e-5;
            elseif  size(ztol,1) ~= 1 || size(ztol,2) ~= 1
                error ('ZTOL cannot be an array.')
            end
            
            
            if nargin < 4
                itmax = 1000;
            elseif isnumeric(itmax) ~= 1
                error ('ITMAX must be a numeric argument.')
            elseif isempty(itmax) == 1
                itmax = 1000;
            elseif  size(itmax,1) ~= 1 || size(itmax,2) ~= 1
                error ('ITMAX cannot be an array.')
            end
            
            
            if isnumeric(Z0) ~= 1
                error ('Z0 must be a vector of three numeric arguments.')
            elseif isempty(Z0) == 1 || length(Z0) ~= 3 || min(size(Z0)) ~= 1
                error ('Z0 must be a vector of length 3 of either complex or real arguments.')
            end
            
            if Z0(1)==Z0(2) || Z0(1)==Z0(3) || Z0(2)==Z0(3)
                error('The initial guesses must be different')
            end
            
            %=============================
            % Begining of Muller's method
            %=============================
            
            z0 = Z0(1);
            z1 = Z0(2);
            z2 = Z0(3);
            
            y0 = feval ( f, z0);
            y1 = feval ( f, z1);
            y2 = feval ( f, z2);
            
            for it = 1:itmax
                q = (z2 - z1)/(z1 - z0);
                A = q*y2 - q*(1+q)*y1 + q^2*y0;
                B = (2*q + 1)*y2 - (1 + q)^2*y1 + q^2*y0;
                C = (1 + q)*y2;
                
                if ( A ~= 0 )
                    
                    disc = B^2 - 4*A*C;
                    
                    den1 = ( B + sqrt ( disc ) );
                    den2 = ( B - sqrt ( disc ) );
                    
                    if ( abs ( den1 ) < abs ( den2 ) )
                        z3 = z2 - (z2 - z1)*(2*C/den2);
                    else
                        z3 = z2 - (z2 - z1)*(2*C/den1);
                    end
                    
                elseif ( B ~= 0 )
                    z3 = z2 - (z2 - z1)*(2*C/B);
                else
                    warning('Muller Method failed to find a root. Last iteration result used as an output. Result may not be accurate')
                    res = z2;
                    fval = y2;
                    return
                end
                
                y3 = feval ( f, z3);
                
                if opt == 1
                    if ( abs (z3 - z2) < ztol || abs ( y3 ) < ftol )
                        res = z3;
                        fval = y3;
                        return
                    end
                else
                    if ( abs (z3 - z2) < ztol && abs ( y3 ) < ftol )
                        res = z3;
                        fval = y3;
                        return
                    end
                end
                
                z0 = z1;
                z1 = z2;
                z2 = z3;
                
                y0 = y1;
                y1 = y2;
                y2 = y3;
                
            end
            
            res = z2;
            fval = y2;
%             warning('Maximum number of iterations reached. Result may not be accurate')
            return;
        end
    
        function result=inv_refl(app,krho,N,E,M,D,selector)
            if selector==0
                result=1./full_RTE(app,krho,N,E,M,D,0);
            elseif selector==1
                result=1./full_RTM(app,krho,N,E,M,D,0);
            end
            return;
        end
    
        function roots=sw_finder(app,N,E,M,D,selector,kmaxx,numofpoints,im_line)
            Ere=real(E);
            Mre=real(M);
            nmaxre=max(real(sqrt(Ere.*Mre)));
            steps=linspace(0,nmaxre*app.k0*kmaxx,numofpoints+1); %% change if after kmax needed steps=linspace(app.k0,nmaxre*app.k0*x,201);
            im_line_r=im_line*app.k0;
            counter=0;
            k=zeros(numofpoints,1);
            steploc=nan(numofpoints,1);
            if im_line==0
                for i=1:numofpoints
                    if selector==0
                        if real(steps(i+1))>app.k0
                            k(i)=bisectionte(app,steps(i),steps(i+1),1e-8,N,Ere,Mre,D);
                            if k(i)~=0
                                counter=counter+1;
                                steploc(counter)=i;
                            end
                        else
                            k(i)=bisectionte(app,steps(i),steps(i+1),1e-8,N,E,M,D);
                            if k(i)~=0
                                counter=counter+1;
                                steploc(counter)=i;
                            end
                        end
                    elseif selector==1
                        if real(steps(i+1))>app.k0
                            k(i)=bisectiontm(app,steps(i),steps(i+1),1e-8,N,Ere,Mre,D);
                            if k(i)~=0
                                counter=counter+1;
                                steploc(counter)=i;
                            end
                        else
                            k(i)=bisectiontm(app,steps(i),steps(i+1),1e-8,N,E,M,D);
                            if k(i)~=0
                                counter=counter+1;
                                steploc(counter)=i;
                            end
                        end
                    end
                end
            else
                for i=1:numofpoints
                    if selector==0
                        k(i)=bisectionte(app,steps(i)+1i*im_line_r,steps(i+1)+1i*im_line_r,1e-8,N,E,M,D);
                        if k(i)~=0
                            counter=counter+1;
                            steploc(counter)=i;
                        end
                    elseif selector==1
                        k(i)=bisectiontm(app,steps(i)+1i*im_line_r,steps(i+1)+1i*im_line_r,1e-8,N,E,M,D);
                        if k(i)~=0
                            counter=counter+1;
                            steploc(counter)=i;
                        end
                    end
                end
            end
            steploc=rmmissing(steploc);
            muller_startlocs=steps(steploc);
            stepinc=steps(2)-steps(1);
            roots2=zeros(counter,1);
            for ii=1:counter
                roots2(ii)=muller(app,@(krho)inv_refl(app,krho,N,E,M,D,selector),[muller_startlocs(ii)-stepinc,muller_startlocs(ii),muller_startlocs(ii)+stepinc],1000,[],1e-12);
            end
            counter=0;
            for ii=1:length(roots2)
                if roots2(ii)~=0 && abs(inv_refl(app,roots2(ii),N,E,M,D,selector))<1e-4
                    counter=counter+1;
                    roots(counter)=roots2(ii); %#ok<AGROW> 
                end
            end
            if counter==0
                roots=0;
            end
        end
        
        function roots=root_finder3(app,N,E,M,D,selector,kmaxx,numofpoints,im_line)
            
            %N: number of layers
            %f:freq
            %E,M: permittivity, permeab.
            %D:thickness
            %selector=0 TE, selector=1 TM.
            klm=sw_finder(app,N,E,M,D,selector,kmaxx,numofpoints,im_line);
            klm=rmmissing(klm); %remove nans if any
            
            root_len=length(klm);
            for ii=1:root_len-1 %replace repeating roots with nan
                for jj=ii+1:root_len
                    if abs(klm(ii))~=0 && abs(klm(ii)-klm(jj))/abs(klm(ii))<1e-4
                        klm(jj)=nan;
                        break;
                    end
                end
            end
            roots=rmmissing(klm); %remove nans
        end
        
        function [rootsall]=root_finder(app,kmaxx,numofpoints,im_line)
            N=app.NN;
            E=app.eps;
            M=app.mu;
            D=app.d;
            kgamma_refl=app.kgamma*app.k0;
            
            rootste=root_finder3(app,N,E,M,D,0,kmaxx,numofpoints,im_line)./app.k0;
            
            if length(rootste)==1 && rootste(1)==0
                fprintf('NO TE-mode singularity is found\n');
                rootste=[];
            else
                rootste=sqrt(rootste.^2-app.kgamma^2);
                ref_valste=full_RTE(app,rootste.*app.k0,N,E,M,D,kgamma_refl);
                fprintf([int2str(length(rootste)) ' TE-MODE SINGULARITIES:\n']);
                fprintf('Singularity (normalized wrt. k_0) | Reflection coefficient at the singularity\n');
                fprintf('----------------------------------|------------------------------------------\n');
                for ii=1:length(rootste)
                    if imag(rootste(ii))==0
                        fprintf(['            ' num2str(rootste(ii),'%10.9f') '           |        ' num2str(ref_valste(ii),'%10.5e') '\n']);
                    else
                        fprintf(['     ' num2str(rootste(ii),'%10.9f') '     |        ' num2str(ref_valste(ii),'%10.5e') '\n']);
                    end
                end
            end
            fprintf('-----------------------------------------------------------------------------\n');
            rootstm=root_finder3(app,N,E,M,D,1,kmaxx,numofpoints,im_line)./app.k0;
            
            if length(rootstm)==1 && rootstm(1)==0
                fprintf('NO TM-mode singularity is found\n');
                rootstm=[];
            else
                rootstm=sqrt(rootstm.^2-app.kgamma^2);
                ref_valstm=full_RTM(app,rootstm.*app.k0,N,E,M,D,kgamma_refl);
                fprintf([int2str(length(rootstm)) ' TM-MODE SINGULARITIES FOUND:\n']);
                fprintf('Singularity (normalized wrt. k_0) | Reflection coefficient at the singularity\n');
                fprintf('----------------------------------|------------------------------------------\n');
                for ii=1:length(rootstm)
                    if imag(rootstm(ii))==0
                        fprintf(['            ' num2str(rootstm(ii),'%10.9f') '           |        ' num2str(ref_valstm(ii),'%10.5e') '\n']);
                    else
                        fprintf(['     ' num2str(rootstm(ii),'%10.9f') '     |        ' num2str(ref_valstm(ii),'%10.5e') '\n']);
                    end
                end
            end
            fprintf('-----------------------------------------------------------------------------\n');
            rootsall=[rootste rootstm];
        end
        
        function branchcut=brach_cut_im(app,branch,kgamma_stop,kgamma_num) %#ok<INUSL> 
            % gets the branch cut for the given branch point
            kgamma_im=linspace(imag(branch),kgamma_stop,kgamma_num);
            kxx=real(branch)*imag(branch);
            kgamma_re=kxx./kgamma_im;
            branchcut=kgamma_re+1j*kgamma_im;
            branchcut(1)=branch;
        end
        
        function [] = branch_plane_plotter( app,xmax,ymax,plot_const,tickx,ticky,tickxloc,tickyloc,kx_or_kgamma )
            % Code for plotting branch points, branch cuts and singularities on a complex k_x or k_gamma plane.
            % Inputs:
            % branch_points: branch points from which the branch cuts originate.
            % singularities: singularities of the reflection coefficient. (plotted only on k_x)
            % xmax,ymax: maximum values on the real and imaginary axes.
            % plot_const: pixelwise pltting constant, look below for its usage.
            % tickx,ticky: tick locations on the x and y axes
            % tickxloc,tickyloc: flags (1 or -1) for plotting the ticks on which side of the axes.
            % k_gamma: when plotting on the k_x axis, normalized k_gamma value.
            % kx_or_kgamma: if 1 plot on k_x plane if 2 plot on k_gamma plane.
            % Aytac Alparslan Jan. 2020.
            figure;
            branch_points=app.branches;
            singularities=app.roots;
            grid on;
            axis equal;
            xlim([-xmax xmax]);
            ylim([-ymax ymax]);
            
            axp = get(gca,'Position');
            xs=axp(1);
            ys=axp(2);
            xe=axp(1)+axp(3);
            ye=axp(2)+axp(4);
            annotation('doublearrow', [(xe+xs)/2 (xe+xs)/2],[ys-0.02 ye+0.02],'LineWidth',1.5);
            annotation('doublearrow', [xs-0.04 xe+0.04],[(ye+ys)/2 (ye+ys)/2],'LineWidth',1.5);
            
            set(gcf,'color','w');
            set(gca,'YTick',[]);
            set(gca,'XTick',[]);
            set(gca,'YColor',get(gca,'Color'));
            set(gca,'XColor',get(gca,'Color'));
            hold off;
            box off;
            
            if kx_or_kgamma==2 %plotting on kgamma plane
                singularities=sqrt(singularities.^2+app.kgamma^2);
                branch_points=[branch_points singularities];
                annotation('textbox', [xe-0.04,(ye+ys)/2-0.11,0.1,0.1],'String','$\Re(k_\gamma/k_0)$','LineStyle','none','FontName','Times New Roman','FontSize',14.0,'Interpreter','latex');
                annotation('textbox', [(xe+xs)/2,ye-0.05,0.1,0.1],'String','$\Im(k_\gamma/k_0)$','LineStyle','none','FontName','Times New Roman','FontSize',14.0,'Interpreter','latex');
            else
                branch_points=sqrt(branch_points.^2-app.kgamma^2); %plotting on kx plane
%                 singularities=sqrt(singularities.^2-kgamma^2);
                annotation('textbox', [xe-0.04,(ye+ys)/2-0.11,0.1,0.1],'String','$\Re(k_x/k_0)$','LineStyle','none','FontName','Times New Roman','FontSize',14.0,'Interpreter','latex');
                annotation('textbox', [(xe+xs)/2,ye-0.05,0.1,0.1],'String','$\Im(k_x/k_0)$','LineStyle','none','FontName','Times New Roman','FontSize',14.0,'Interpreter','latex');
            end
            % set(gcf, 'Units', 'centimeters', 'Position', [1.2, 1.2, 18, 14]);
            screen_size=get(0,'screensize');
            if (300+2*xmax*plot_const)<screen_size(3) && (300+2*ymax*plot_const)<screen_size(4)
                set(gcf,'Position',[100 100 100+2*xmax*plot_const 100+2*ymax*plot_const])
            else
                plot_const_x=(screen_size(3)-300)/(2*xmax);
                plot_const_y=(screen_size(4)-300)/(2*ymax);
                plot_const=min(plot_const_x,plot_const_y);
                set(gcf,'Position',[100 100 100+2*xmax*plot_const 100+2*ymax*plot_const])
            end
            hold on;
            for ii=1:length(branch_points)
                if abs(imag(branch_points(ii)))>abs(ymax) || abs(real(branch_points(ii)))>abs(xmax)
                    warning('A branch point is outside the plot area!');
                end
                if imag(branch_points(ii))>0
                    branch_cut=brach_cut_im(app,branch_points(ii),ymax,1000);
                else
                    branch_cut=brach_cut_im(app,branch_points(ii),-ymax,1000);
                end
                plot(real(branch_points(ii)),imag(branch_points(ii)),'ro','LineWidth',2,'MarkerSize',4);
                if ~isempty(singularities)
                    plot(real(singularities(1)),imag(singularities(1)),'bx','LineWidth',2,'MarkerSize',5);
                end
                plot(branch_cut,'r','LineWidth',4);
                plot(-branch_cut,'r','LineWidth',4);
                plot(real(-branch_points(ii)),imag(-branch_points(ii)),'ro','LineWidth',2,'MarkerSize',4);
            end
            
            for ii=1:length(singularities)
                plot(real(singularities(ii)),imag(singularities(ii)),'bx','LineWidth',2,'MarkerSize',5);
                plot(real(-singularities(ii)),imag(-singularities(ii)),'bx','LineWidth',2,'MarkerSize',5);
            end
            
            for ii=1:length(tickx)
                line([tickx(ii) tickx(ii)],[-0.04 0.04],'LineWidth',0.75,'Color','k');
                text(tickx(ii)-0.04,tickxloc(ii)*0.125,num2str(tickx(ii)),'FontName','Times New Roman','FontSize',12);
            end
            
            for ii=1:length(ticky)
                line([-0.04 0.04],[ticky(ii) ticky(ii)],'LineWidth',0.75,'Color','k');
                text(tickyloc(ii)*0.125,ticky(ii),num2str(ticky(ii)),'FontName','Times New Roman','FontSize',12);
            end
            if ~isempty(singularities)
                hh=legend('Branch Points','SWPs','Branch cuts');
            else
                hh=legend('Branch Points','Branch cuts');
            end
            hh.FontName='Times New Roman';
            hh.FontSize=12;
            hh.Location='NorthWest';
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.wav = 500e-9; %default wavelength is 500nm.
            app.k0=2*pi/app.wav; %default wavenumber.
            app.freq=app.c0/app.wav; %corresponding default frequency.
            app.eps = [2+0.5i 4 1+0.3i]; %default epsilon values.
            app.mu = [1 1 1]; %default mu values.
            app.pol = 0; %default polarization (0+1: TE, 1+1: TM)
            app.d = 150e-9; %default d value.
            app.re_start = 0; %default start value of the real part of the krho values.
            app.re_stop = 3; %default stop value of the real part of the krho values.
            app.re_n = 1000; %default number of values of the real part of the krho values.
            app.im_start = 0; %default start value of the imaginary part of the krho values.
            app.im_stop = 0; %default stop value of the imaginary part of the krho values.
            app.im_n = 1; %default number of values of the imaginary part of the krho values.
            app.plot_flag=4; %default plot flag value is 4 (log10(abs())).
            app.root_flag=1; %default value for root search flag.
            app.branch_flag=1; %default value for brach cut plot flag.
            app.NN=3; %default value for number of layers.
            app.kgamma=0; %default value for k_gamma.
            app.roots=[]; %no roots are found at the beginning.
            app.branches=sqrt([app.eps(1)*app.mu(1) app.eps(app.NN)*app.mu(app.NN)]); %branch points for the default layered geometry.
        end

        % Value changed function: FreespacewavelengthEditField
        function FreespacewavelengthEditFieldValueChanged(app, event)
            app.wav = app.FreespacewavelengthEditField.Value*1e-9; % update the app.wav in m.
            app.freq=app.c0/app.wav; % frequency
            freqthz(1)=app.freq/1e12; % frequency in THz
            app.k0=2*pi/app.wav; % free-space wavenumber
            app.THzLabel.Text=[num2str(freqthz) ' THz']; % update the frequency
            eps_au=eps_val(app,1); % get gold's permittivity
            if isempty(eps_au)
                app.au_val.Text='OUT OF BOUNDS'; % check if the value is empty
            else
                app.au_val.Text=num2str(eps_au); % update gold's permittivity
            end
            eps_ag=eps_val(app,2); % get silver's permittivity
            if isempty(eps_ag)
                app.ag_val.Text='OUT OF BOUNDS'; % check if the value is empty
            else
                app.ag_val.Text=num2str(eps_ag); % update silver's permittivity
            end
            eps_al=eps_val(app,3); % get aluminium's permittivity
            if isempty(eps_al)
                app.al_val.Text='OUT OF BOUNDS'; % check if the value is empty
            else
                app.al_val.Text=num2str(eps_al); % update aluminium's permittivity
            end
            
            app.PermittivitiesEditField.FontColor='black';
            value = app.PermittivitiesEditField.Value;
            b=erase(value,{'[',']'}); % delete the vector brackets
            a=split(b,{' ',','})'; % split the values by space or ','
            app.eps = str2double(a); % update the app.eps vector.
            if sum(isnan(app.eps))>0 %check if there is a nan in eps
                metal_check(app,a); %check if there is a metal in the vector
            end
            if sum(imag(app.eps)<0)>0 %check if there is a complex entry with negative imaginary part in mu
                errordlg('Check the permittivity values: they should be non-active, i.e. imaginary parts should be larger than or equal to 0!');
                app.PermittivitiesEditField.FontColor='red';
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox('Created by Aytac Alparslan. Jan. 2020. ver.0.0','About');
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            app.NN=length(app.eps); % number of layers defined by the permittivity length.
            if app.NN < 2
                errordlg('Number of layers should be larger than 2!');
                return;
            end
            if app.NN ~= length(app.mu)
                errordlg(['Please check permeability values: they should be as many as the permittivity values. (N= ' num2str(app.NN) ')']);
                app.PermeabilitesEditField.FontColor='red';
                return;
            end
            if app.NN-2 ~= length(app.d) && app.NN > 2
                errordlg(['Please check thickness values: they should be two less than the permittivity values. (N= ' num2str(app.NN) ')']);
                app.ThicknessesEditField.FontColor='red';
                return;
            end
            krhoreal=linspace(app.start_re.Value,app.stop_re.Value,app.n_re.Value); 
            krhoimag=linspace(app.start_im.Value,app.stop_im.Value,app.n_im.Value);
            if strcmp(app.PolarizationSwitch.Value,'TE')
                pol_indent=1;
            else
                pol_indent=2;
            end
                        
            refdata_plane( app,krhoreal,krhoimag,pol_indent );
            if app.TrytofindsingularitiesCheckBox.Value
                app.roots=root_finder(app,abs(app.stop_re.Value),app.n_re.Value,app.imagsearchlineEditField.Value);
            end
            if app.PlotbranchcutsCheckBox.Value
                app.branches=sqrt([app.eps(1)*app.mu(1) app.eps(app.NN)*app.mu(app.NN)]);
                value_k_plane=app.kx_or_kgamma_dropdown.Value;
                xmax=max(abs(app.stop_re.Value),abs(app.start_re.Value));
                ymax=max(abs(app.stop_im.Value),abs(app.start_im.Value));
                if xmax==0 && ymax==0
                    errordlg('Please enter valid window parameters for the plots');
                    return;
                elseif xmax==0
                    xmax=ymax;
                elseif ymax==0
                    ymax=xmax;
                end
                tickx=-xmax:1:-1;
                tickx=[tickx -tickx];
                ticky=-ymax:1:-1;
                ticky=[ticky -ticky];
                if strcmp(value_k_plane,'$k_x$')
                    branch_plane_plotter( app,xmax,ymax,100,tickx,ticky,ones(size(tickx)),ones(size(ticky)),1 );
                else
                    branch_plane_plotter( app,xmax,ymax,100,tickx,ticky,ones(size(tickx)),ones(size(ticky)),2 );
                end
                
            end

        end

        % Value changed function: ThicknessesEditField
        function ThicknessesEditFieldValueChanged(app, event)
            app.ThicknessesEditField.FontColor='black'; %if there is no problem with the entries set the color black
            value = app.ThicknessesEditField.Value; % read the value from the thickness box
            app.d=str2num(value).*1e-9; %#ok<ST2NM> 
            if sum(isnan(app.d))>0 %check if there is a nan in d
                errordlg('Check the thickness values they should be numeric, seperated by a space or comma');
                app.ThicknessesEditField.FontColor='red';
            end
            if sum(abs(imag(app.d))>0)>0 %check if there is a complex entry in d
                errordlg('Check the thickness values they should be real!');
                app.ThicknessesEditField.FontColor='red';
            end
            if sum(app.d<=0)>0 %check if there is a negative entry in d
                errordlg('Check the thickness values they should be larger than 0!');
                app.ThicknessesEditField.FontColor='red';
            end
        end

        % Value changed function: PermeabilitesEditField
        function PermeabilitesEditFieldValueChanged(app, event)
            app.PermeabilitesEditField.FontColor='black'; %if there is no problem with the entries set the color black
            value = app.PermeabilitesEditField.Value; % read the value from the permabilities box
%             b=erase(value,{'[',']'}); % delete the vector brackets
%             a=split(b,{' ',','})'; % split the values by space or ','
%             app.mu = str2double(a); % update the app.mu vector.
            app.mu=str2num(value); %#ok<ST2NM> 
            if sum(isnan(app.mu))>0 %check if there is a nan in mu
                errordlg('Check the permeability values they should be numeric, seperated by a space or comma');
                app.PermeabilitesEditField.FontColor='red';
            end
            if sum(imag(app.mu)<0)>0 %check if there is a complex entry with negative imaginary part in mu
                errordlg('Check the permeability values: they should be non-active, i.e. imaginary parts should be larger than or equal to 0!');
                app.PermeabilitesEditField.FontColor='red';
            end
        end

        % Value changed function: k_gammaEditField
        function k_gammaEditFieldValueChanged(app, event)
            app.k_gammaEditField.FontColor='black'; %if there is no problem with the entries set the color black
            value = app.k_gammaEditField.Value; % read the value from the k_gamma box
            app.kgamma = str2double(value); % update the app.k_gamma value.
            if length(app.kgamma)~=1 || isnan(app.kgamma)
                errordlg('Check the k_gamma value it should be a single numerical value');
                app.k_gammaEditField.FontColor='red';
            end
            if imag(app.kgamma)<0
                errordlg('Check the k_gamma value its imaginary part should be larger than or equal to 0!');
                app.k_gammaEditField.FontColor='red';
            end
            if real(app.kgamma)<0
                errordlg('Check the k_gamma value its real part should be larger than or equal to 0!');
                app.k_gammaEditField.FontColor='red';
            end
        end

        % Value changed function: PermittivitiesEditField
        function PermittivitiesEditFieldValueChanged(app, event)
            app.PermittivitiesEditField.FontColor='black';
            value = app.PermittivitiesEditField.Value;
            b=erase(value,{'[',']'}); % delete the vector brackets
            a=split(b,{' ',','})'; % split the values by space or ','
            app.eps = str2double(a); % update the app.eps vector.
            if sum(isnan(app.eps))>0 %check if there is a nan in eps
                metal_check(app,a); %check if there is a metal in the vector
            end
            if sum(imag(app.eps)<0)>0 %check if there is a complex entry with negative imaginary part in mu
                errordlg('Check the permittivity values: they should be non-active, i.e. imaginary parts should be larger than or equal to 0!');
                app.PermittivitiesEditField.FontColor='red';
            end
            if length(app.eps)==2
                app.ThicknessesEditField.Enable='off';
            else
                app.ThicknessesEditField.Enable='on';
            end
        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotTypeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Re()')
                app.plot_flag=1;
            elseif strcmp(selectedButton.Text,'Im()')
                app.plot_flag=2;
            elseif strcmp(selectedButton.Text,'abs()')
                app.plot_flag=3;
            elseif strcmp(selectedButton.Text,'log10(abs())')
                app.plot_flag=4;
            else
                app.plot_flag=5;
            end
        end

        % Value changed function: TrytofindsingularitiesCheckBox
        function TrytofindsingularitiesCheckBoxValueChanged(app, event)
            value = app.TrytofindsingularitiesCheckBox.Value;
            if ~value
                app.imagsearchlineEditField.Enable='off';
                app.imagsearchlineEditFieldLabel.Enable='off';
                app.k_0Label_2.Enable='off';
            else
                app.imagsearchlineEditField.Enable='on';
                app.imagsearchlineEditFieldLabel.Enable='on'; 
                app.k_0Label_2.Enable='on';
            end
        end

        % Button pushed function: DescriptionButton
        function DescriptionButtonPushed(app, event)
            open('sketch.pdf');
        end

        % Value changed function: PlotbranchcutsCheckBox
        function PlotbranchcutsCheckBoxValueChanged(app, event)
            value = app.PlotbranchcutsCheckBox.Value;
            if value
                app.kx_or_kgamma_dropdown.Enable='on';
                app.onDropDownLabel.Enable='on';
                app.planeLabel.Enable='on';
            else
                app.kx_or_kgamma_dropdown.Enable='off';
                app.planeLabel.Enable='off';
                app.onDropDownLabel.Enable='off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReflectionCoefficentPlotterUIFigure = uifigure('Visible', 'off');
            app.ReflectionCoefficentPlotterUIFigure.Name = 'Reflection Coefficent Plotter';
            app.Menu = uimenu(app.ReflectionCoefficentPlotterUIFigure);
            app.Menu.Text = 'Menu';
            app.AboutMenu = uimenu(app.Menu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.ReflectionCoefficientPlotterLabel = uilabel(app.ReflectionCoefficentPlotterUIFigure);
            app.ReflectionCoefficientPlotterLabel.Text = 'Reflection Coefficient Plotter';
            app.GeometrySettingsPanel = uipanel(app.ReflectionCoefficentPlotterUIFigure);
            app.GeometrySettingsPanel.Tooltip = {''};
            app.GeometrySettingsPanel.Title = 'Geometry Settings';
            app.nmLabel = uilabel(app.GeometrySettingsPanel);
            app.nmLabel.Text = 'nm';
            app.FreespacewavelengthEditFieldLabel = uilabel(app.GeometrySettingsPanel);
            app.FreespacewavelengthEditFieldLabel.Text = 'Free-space wavelength';
            app.FreespacewavelengthEditField = uieditfield(app.GeometrySettingsPanel, 'numeric');
            app.FreespacewavelengthEditField.Limits = [0 Inf];
            app.FreespacewavelengthEditField.ValueDisplayFormat = '%11.2f';
            app.FreespacewavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @FreespacewavelengthEditFieldValueChanged, true);
            app.FreespacewavelengthEditField.Tooltip = {'Free-space wavelength of the wave in (nm).'};
            app.FreespacewavelengthEditField.Value = 500;
            app.PermittivitiesEditFieldLabel = uilabel(app.GeometrySettingsPanel);
            app.PermittivitiesEditFieldLabel.Text = 'Permittivities';
            app.PermittivitiesEditField = uieditfield(app.GeometrySettingsPanel, 'text');
            app.PermittivitiesEditField.ValueChangedFcn = createCallbackFcn(app, @PermittivitiesEditFieldValueChanged, true);
            app.PermittivitiesEditField.Tooltip = {'Relative permittivity values of the layers, starting from the lower-most layer-1 (Unitless). Can enter *au for gold and *ag for silver.'};
            app.PermittivitiesEditField.Value = '[2+0.5i 4 1+0.3i]';
            app.PermeabilitesEditFieldLabel = uilabel(app.GeometrySettingsPanel);
            app.PermeabilitesEditFieldLabel.Text = 'Permeabilites';
            app.PermeabilitesEditField = uieditfield(app.GeometrySettingsPanel, 'text');
            app.PermeabilitesEditField.ValueChangedFcn = createCallbackFcn(app, @PermeabilitesEditFieldValueChanged, true);
            app.PermeabilitesEditField.Tooltip = {'Relative permeability values of the layers, starting from the lower-most layer-1 (Unitless).'};
            app.PermeabilitesEditField.Value = '[1 1 1]';
            app.ThicknessesEditFieldLabel = uilabel(app.GeometrySettingsPanel);
            app.ThicknessesEditFieldLabel.Text = 'Thicknesses';
            app.ThicknessesEditField = uieditfield(app.GeometrySettingsPanel, 'text');
            app.ThicknessesEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessesEditFieldValueChanged, true);
            app.ThicknessesEditField.Tooltip = {'Thickness values of the layers, starting from layer-2 (nm). '};
            app.ThicknessesEditField.Value = '[150]';
            app.nmLabel_2 = uilabel(app.GeometrySettingsPanel);
            app.nmLabel_2.Text = 'nm';
            app.PolarizationSwitchLabel = uilabel(app.GeometrySettingsPanel);
            app.PolarizationSwitchLabel.Text = 'Polarization';
            app.PolarizationSwitch = uiswitch(app.GeometrySettingsPanel, 'slider');
            app.PolarizationSwitch.Items = {'TM', 'TE'};
            app.PolarizationSwitch.Tooltip = {'Polarization chooser. '; 'TE: E_y~=0'; 'TM: H_y~=0'; 'The layers are stacked in z-direction.'};
            app.PolarizationSwitch.Value = 'TE';
            app.k_gammaLabel = uilabel(app.GeometrySettingsPanel);
            app.k_gammaLabel.Text = '$k_\gamma$:';
            app.k_gammaEditField = uieditfield(app.GeometrySettingsPanel, 'text');
            app.k_gammaEditField.ValueChangedFcn = createCallbackFcn(app, @k_gammaEditFieldValueChanged, true);
            app.k_gammaEditField.Tooltip = {'Normalized k_\gamma value wrt k_0 (Unitless) in y-direction. '};
            app.k_gammaEditField.Value = '0';
            app.k_0Label = uilabel(app.GeometrySettingsPanel);
            app.k_0Label.Text = '$k_0$';
            app.THzLabel = uilabel(app.GeometrySettingsPanel);
            app.THzLabel.Text = '599.5849 THz';
            app.SpectralDomainSettingsk_xnormalizedwrtk_0Panel = uipanel(app.ReflectionCoefficentPlotterUIFigure);
            app.SpectralDomainSettingsk_xnormalizedwrtk_0Panel.Title = 'Spectral Domain Settings (k_x normalized wrt. k_0)';
            app.StartLabel = uilabel(app.SpectralDomainSettingsk_xnormalizedwrtk_0Panel);
            app.StartLabel.Text = 'Start';
            app.StopLabel = uilabel(app.SpectralDomainSettingsk_xnormalizedwrtk_0Panel);
            app.StopLabel.Text = 'Stop';
            app.Label = uilabel(app.SpectralDomainSettingsk_xnormalizedwrtk_0Panel);
            app.Label.Text = '#';
            app.RealpartLabel = uilabel(app.SpectralDomainSettingsk_xnormalizedwrtk_0Panel);
            app.RealpartLabel.Text = 'Real part';
            app.ImaginarypartLabel = uilabel(app.SpectralDomainSettingsk_xnormalizedwrtk_0Panel);
            app.ImaginarypartLabel.Text = 'Imaginary part';
            app.start_re = uieditfield(app.SpectralDomainSettingsk_xnormalizedwrtk_0Panel, 'numeric');
            app.stop_re = uieditfield(app.SpectralDomainSettingsk_xnormalizedwrtk_0Panel, 'numeric');
            app.stop_re.Value = 3;
            app.n_re = uieditfield(app.SpectralDomainSettingsk_xnormalizedwrtk_0Panel, 'numeric');
            app.n_re.Limits = [0 Inf];
            app.n_re.Value = 1000;
            app.start_im = uieditfield(app.SpectralDomainSettingsk_xnormalizedwrtk_0Panel, 'numeric');
            app.stop_im = uieditfield(app.SpectralDomainSettingsk_xnormalizedwrtk_0Panel, 'numeric');
            app.n_im = uieditfield(app.SpectralDomainSettingsk_xnormalizedwrtk_0Panel, 'numeric');
            app.n_im.Limits = [0 Inf];
            app.n_im.Value = 1;
            app.PermittivityValuesofMetalsPanel = uipanel(app.ReflectionCoefficentPlotterUIFigure);
            app.PermittivityValuesofMetalsPanel.Tooltip = {'Relative permittvity values of some metals at the given free-space wavelength value. To use the metals as a layer, enter *au or *ag in the corresponding layer''s permittivity value on the left.'};
            app.PermittivityValuesofMetalsPanel.Title = 'Permittivity Values of Metals';
            app.AuLabel = uilabel(app.PermittivityValuesofMetalsPanel);
            app.AuLabel.Text = 'Au:';
            app.AgLabel = uilabel(app.PermittivityValuesofMetalsPanel);
            app.AgLabel.Text = 'Ag:';
            app.au_val = uilabel(app.PermittivityValuesofMetalsPanel);
            app.au_val.Tooltip = {'Relative permittivity of Gold at the given wavelength value. (data between 300-1600nm is avalible.)'};
            app.au_val.Text = {'-2.0993+2.4731i'; ''};
            app.ag_val = uilabel(app.PermittivityValuesofMetalsPanel);
            app.ag_val.Tooltip = {'Relative permittivity of Silver at the given wavelength value. (data between 272-900nm is avalible.)'};
            app.ag_val.Text = {'-9.621+0.31022i'; ''};
            app.AlLabel = uilabel(app.PermittivityValuesofMetalsPanel);
            app.AlLabel.Text = 'Al:';
            app.al_val = uilabel(app.PermittivityValuesofMetalsPanel);
            app.al_val.Tooltip = {'Relative permittivity of Silver at the given wavelength value. (data between 272-900nm is avalible.)'};
            app.al_val.Text = '-36.8757+9.56677i';
            app.PlotTypeButtonGroup = uibuttongroup(app.ReflectionCoefficentPlotterUIFigure);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Tooltip = {'Choose the plot type.'};
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.ReButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ReButton.Text = 'Re()';
            app.ImButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ImButton.Text = 'Im()';
            app.absButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.absButton.Text = 'abs()';
            app.log10absButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.log10absButton.Text = 'log10(abs())';
            app.log10absButton.Value = true;
            app.angleButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.angleButton.Text = 'angle()';
            app.PLOTButton = uibutton(app.ReflectionCoefficentPlotterUIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.AdditionalInformationPanel = uipanel(app.ReflectionCoefficentPlotterUIFigure);
            app.AdditionalInformationPanel.Tooltip = {''};
            app.AdditionalInformationPanel.Title = 'Additional Information';
            app.TrytofindsingularitiesCheckBox = uicheckbox(app.AdditionalInformationPanel);
            app.TrytofindsingularitiesCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrytofindsingularitiesCheckBoxValueChanged, true);
            app.TrytofindsingularitiesCheckBox.Text = 'Try to find singularities?';
            app.TrytofindsingularitiesCheckBox.Value = true;
            app.PlotbranchcutsCheckBox = uicheckbox(app.AdditionalInformationPanel);
            app.PlotbranchcutsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotbranchcutsCheckBoxValueChanged, true);
            app.PlotbranchcutsCheckBox.Text = 'Plot branch cuts?';
            app.imagsearchlineEditFieldLabel = uilabel(app.AdditionalInformationPanel);
            app.imagsearchlineEditFieldLabel.Text = 'imag(search line)';
            app.imagsearchlineEditField = uieditfield(app.AdditionalInformationPanel, 'numeric');
            app.imagsearchlineEditField.Tooltip = {'Enter the imaginary part of the search line for the singularities.'};
            app.k_0Label_2 = uilabel(app.AdditionalInformationPanel);
            app.k_0Label_2.Text = '$k_0$';
            app.onDropDownLabel = uilabel(app.AdditionalInformationPanel);
            app.onDropDownLabel.Enable = 'off';
            app.onDropDownLabel.Text = 'on';
            app.kx_or_kgamma_dropdown = uidropdown(app.AdditionalInformationPanel);
            app.kx_or_kgamma_dropdown.Items = {'$k_x$', '$k_\gamma$'};
            app.kx_or_kgamma_dropdown.Enable = 'off';
            app.kx_or_kgamma_dropdown.Value = '$k_x$';
            app.planeLabel = uilabel(app.AdditionalInformationPanel);
            app.planeLabel.Enable = 'off';
            app.planeLabel.Text = 'plane';
            app.DescriptionButton = uibutton(app.ReflectionCoefficentPlotterUIFigure, 'push');
            app.DescriptionButton.ButtonPushedFcn = createCallbackFcn(app, @DescriptionButtonPushed, true);
            app.DescriptionButton.Text = 'Description';
            % Show the figure after all components are created
            app.ReflectionCoefficentPlotterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ref_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReflectionCoefficentPlotterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReflectionCoefficentPlotterUIFigure)
        end
    end
end